classdef Application_PF_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Processingof3DPFsUIFigure      matlab.ui.Figure
        BrowseButton                   matlab.ui.control.StateButton
        BrowseButton_2                 matlab.ui.control.StateButton
        ShowcurvatureofindividualprocessedPFsLabel  matlab.ui.control.Label
        YesNoCheckBox                  matlab.ui.control.CheckBox
        StartProcessingButton          matlab.ui.control.StateButton
        PFsfilesdirectorydatEditFieldLabel  matlab.ui.control.Label
        PFsfilesdirectorydatEditField  matlab.ui.control.EditField
        NameofdatasetEditFieldLabel    matlab.ui.control.Label
        NameofdatasetEditField         matlab.ui.control.EditField
        FolderfortheresultsEditFieldLabel  matlab.ui.control.Label
        FolderfortheresultsEditField   matlab.ui.control.EditField
        PixelsizenmEditFieldLabel      matlab.ui.control.Label
        PixelsizenmEditField           matlab.ui.control.NumericEditField
        Processingof3DprotofilamentsLabel  matlab.ui.control.Label
        CloseallfiguresButton          matlab.ui.control.StateButton
        ViewindividualPFsbeforesmoothingLabel  matlab.ui.control.Label
        YesNoCheckBox_2                matlab.ui.control.CheckBox
    end

    methods (Access = public)
        function mainfunc(app)
            Main_process_PFs_3D(app.NameofdatasetEditField.Value,...
                app.PFsfilesdirectorydatEditField.Value,...
                app.FolderfortheresultsEditField.Value,...
                app.PixelsizenmEditField.Value,...
                app.YesNoCheckBox.Value, app.YesNoCheckBox_2.Value)
        end
    end

    methods (Access = private)

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            app.PFsfilesdirectorydatEditField.Value = uigetdir();
            app.BrowseButton.Value = 0;
            figure(app.Processingof3DPFsUIFigure)
        end

        % Value changed function: BrowseButton_2
        function BrowseButton_2ValueChanged(app, event)
            app.FolderfortheresultsEditField.Value = uigetdir();
            app.BrowseButton_2.Value = 0;
            figure(app.Processingof3DPFsUIFigure)
        end

        % Value changed function: StartProcessingButton
        function StartProcessingButtonValueChanged(app, event)
            mainfunc(app)
            app.StartProcessingButton.Value = 0;
        end

        % Value changed function: CloseallfiguresButton
        function CloseallfiguresButtonValueChanged(app, event)
            close all
            app.CloseallfiguresButton.Value = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Processingof3DPFsUIFigure = uifigure;
            app.Processingof3DPFsUIFigure.Name = 'Processing of 3D PFs';
            app.BrowseButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse...';
            app.BrowseButton_2 = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.BrowseButton_2.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_2ValueChanged, true);
            app.BrowseButton_2.Text = 'Browse...';
            app.ShowcurvatureofindividualprocessedPFsLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.ShowcurvatureofindividualprocessedPFsLabel.Text = {'Show curvature of '; 'individual processed PFs'};
            app.YesNoCheckBox = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox.Text = 'Yes/No';
            app.StartProcessingButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.StartProcessingButton.ValueChangedFcn = createCallbackFcn(app, @StartProcessingButtonValueChanged, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.PFsfilesdirectorydatEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.PFsfilesdirectorydatEditFieldLabel.Text = 'PFs files directory (.dat)';
            app.PFsfilesdirectorydatEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.NameofdatasetEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.NameofdatasetEditFieldLabel.Text = 'Name of data set';
            app.NameofdatasetEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.FolderfortheresultsEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.FolderfortheresultsEditFieldLabel.Text = 'Folder for the results';
            app.FolderfortheresultsEditField = uieditfield(app.Processingof3DPFsUIFigure, 'text');
            app.PixelsizenmEditFieldLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.PixelsizenmEditFieldLabel.Text = 'Pixel size, nm';
            app.PixelsizenmEditField = uieditfield(app.Processingof3DPFsUIFigure, 'numeric');
            app.PixelsizenmEditField.Value = 1;
            app.Processingof3DprotofilamentsLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.Processingof3DprotofilamentsLabel.Text = 'Processing of 3D protofilaments';
            app.CloseallfiguresButton = uibutton(app.Processingof3DPFsUIFigure, 'state');
            app.CloseallfiguresButton.ValueChangedFcn = createCallbackFcn(app, @CloseallfiguresButtonValueChanged, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.ViewindividualPFsbeforesmoothingLabel = uilabel(app.Processingof3DPFsUIFigure);
            app.ViewindividualPFsbeforesmoothingLabel.Text = {'View individual PFs '; 'before smoothing'};
            app.YesNoCheckBox_2 = uicheckbox(app.Processingof3DPFsUIFigure);
            app.YesNoCheckBox_2.Text = 'Yes/No';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Application_PF_processing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Processingof3DPFsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Processingof3DPFsUIFigure)
        end
    end
end